classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        InvalidcountEditField         matlab.ui.control.NumericEditField
        InvalidcountLabel             matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        ScoreLabel                    matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        ResultLabel                   matlab.ui.control.Label
        PassingMarksEditField         matlab.ui.control.NumericEditField
        PassingMarksLabel             matlab.ui.control.Label
        LoadStudentAnswerSheetButton  matlab.ui.control.Button
        LoadCorrectAnswerSheetButton  matlab.ui.control.Button
        ResetAllButton                matlab.ui.control.Button
        EvaluateResultButton          matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    % Property to store the loaded image
    properties (Access = private)
        ansImg % Store the Correct answer image
        testImg% Store the Student answer image
        countLine
        % Centers coordinates (N x 2 matrix)
        wrongAnswerCenters
        correctAnswerCenters
        invalidAnswerCenters
        % radii (N x 1 matrix)
        wrongAnswerRadii
        correctAnswerRadii
        invalidAnswerRadii
    end
    
    methods (Access = private)
        
        function creat_Overlayed_MaskImage_of_Wrong_Answer(app)
            %--------------------------------------------------------------------------
            % Create Mask  by Thresholding  test image
            [BW] = createMask(app.testImg);
            %--------------------------------------------------------------------------
            % Overlay created mask over answer image
            masked=app.ansImg;
            masked(repmat(~BW,1,1,3))=0;
            %--------------------------------------------------------------------------

            % Segment the image to get only empty circles(Uncorrect answer)
            [maskedImage] = segmentImage(masked);
            %-----------------------------------------------------------------------
            % Apply close morphology to get clear circle pixel
            [maskedImage] = segmentImageCloseCircles(maskedImage);
            %-------------------------------------------------------------------------

            % Detect wrong answer circles and store coordinates and radius of circles
            [app.wrongAnswerCenters,app.wrongAnswerRadii] = imfindcircles(maskedImage, [6 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.9);
            %----------------------------------------------------------------------------------------------------------------
            
            %numCircles = size(app.centers, 1); % Number of rows in centers

            %app.EditField_2.Value= 20-numCircles; %Calculate Marks by total marks - total wrong answer

            %Check student is pass or not
            %if app.PassingMarksEditField.Value <= (20-numCircles)
            %    app.EditField.Value= 'PASS';
            %else
            %    app.EditField.Value= 'FAIL';
            %end

            
        end
        
        function hide_wrongAnswer(app)
            %----Create a copy of test image-------
            newImage=app.testImg;
            % Hide wrong answer with red color on new image
            for i = 1:length(app.wrongAnswerRadii)
                newImage = insertShape(newImage, 'filled-circle', [app.wrongAnswerCenters(i, :) app.wrongAnswerRadii(i)+2], 'Color', 'red');
            end

            % apply threshold to get binary image of correct answer
            [correctAnswerBinaryImage] = createMaskCorrectAnswer(newImage);

            % Detect correct circles and store coordinates and radius of circles
            [app.correctAnswerCenters, app.correctAnswerRadii] = imfindcircles(correctAnswerBinaryImage, [6 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.9);            
        end

        function detect_InvalidAnswer(app)
            app.invalidAnswerCenters=[];
            app.invalidAnswerRadii=[];
            app.countLine=0;
            flag=0;
            for i = 1:length(app.correctAnswerRadii)
                for j = 1:length(app.wrongAnswerRadii)
                    x1 = app.correctAnswerCenters(i, 1); 
                    y1 = app.correctAnswerCenters(i, 2);
                    x2 = app.wrongAnswerCenters(j, 1); 
                    y2 = app.wrongAnswerCenters(j, 2);

                    r1=app.correctAnswerRadii(i);
                    r2=app.wrongAnswerRadii(j);

                    if y1-3 <= y2 && y2 <= y1+3
                        if (93<x1 && x1 <230) && (93<x2 && x2<230)
                            flag=1;
                            app.invalidAnswerCenters=[app.invalidAnswerCenters;x1 y1];
                            app.invalidAnswerRadii=[app.invalidAnswerRadii;r1];
                            app.invalidAnswerCenters=[app.invalidAnswerCenters;x2 y2];
                            app.invalidAnswerRadii=[app.invalidAnswerRadii;r2];
                            
                        end
                        if (295 < x1 && x1 < 435) && (295 < x2 && x2 < 435)
                            flag=1;
                            app.invalidAnswerCenters=[app.invalidAnswerCenters;x1 y1];
                            app.invalidAnswerRadii=[app.invalidAnswerRadii;r1];
                            app.invalidAnswerCenters=[app.invalidAnswerCenters;x2 y2];
                            app.invalidAnswerRadii=[app.invalidAnswerRadii;r2];
                            
                        end
                    end        
                end
                if flag ==1
                    app.countLine=app.countLine+1;
                    flag=0;
                end
            end
        end

        function displayScore(app)
            num_of_correctAnswer = size(app.correctAnswerCenters, 1); % Number of rows in correctAnswerCenters

            app.InvalidcountEditField.Value=app.countLine;
            app.EditField_2.Value= num_of_correctAnswer-app.countLine; %Calculate Marks by total marks - total wrong answer

           % Check student is pass or not
            if app.PassingMarksEditField.Value <= num_of_correctAnswer-app.countLine
                app.EditField.Value= 'PASS';
            else
                app.EditField.Value= 'FAIL';
            end
        end
        
        function highlightAllAnswers(app)

            copyImage=app.testImg;
            for i = 1:length(app.wrongAnswerRadii)
                copyImage = insertShape(copyImage, 'Circle', [app.wrongAnswerCenters(i, :) app.wrongAnswerRadii(i)], 'Color', 'red', 'LineWidth', 3);
            end
            for i = 1:length(app.correctAnswerRadii)
                copyImage = insertShape(copyImage, 'Circle', [app.correctAnswerCenters(i, :) app.correctAnswerRadii(i)], 'Color', 'green', 'LineWidth', 3);
            end
            for i = 1:length(app.invalidAnswerRadii)
                copyImage = insertShape(copyImage, 'Circle', [app.invalidAnswerCenters(i, :) app.invalidAnswerRadii(i)], 'Color', 'yellow', 'LineWidth', 3);
            end


            imshow(copyImage, 'Parent', app.UIAxes_2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCorrectAnswerSheetButton
        function LoadCorrectAnswerSheetButtonPushed(app, event)
            % Open file selection dialog to load image
            [file, path] = uigetfile({'*.png;*.jpg', 'Image Files'}, 'Select an Image');
    
            % Check if a file was selected
            if file ~= 0
                % Construct the full file path
                filePath = fullfile(path, file);
        
                % Read and store the image
                app.ansImg = imread(filePath);
        
                % Display the image on the Axes
                imshow(app.ansImg, 'Parent', app.UIAxes);

     
            else
                % Display a message if no file is selected
                disp('No image selected');
            end
        end

        % Button pushed function: LoadStudentAnswerSheetButton
        function LoadStudentAnswerSheetButtonPushed(app, event)
            % Open file selection dialog to load image
            [file, path] = uigetfile({'*.png;*.jpg;', 'Image Files'}, 'Select an Image');
    
            % Check if a file was selected
            if file ~= 0
                % Construct the full file path
                filePath = fullfile(path, file);
        
                % Read and display the image on the Axes
                app.testImg = imread(filePath);
                imshow(app.testImg, 'Parent', app.UIAxes_2); % 'UIAxes' is the Axes component
            else
                % Display a message if no file is selected
                disp('No image selected');
            end
        end

        % Button pushed function: EvaluateResultButton
        function EvaluateResultButtonPushed(app, event)
            
            
            creat_Overlayed_MaskImage_of_Wrong_Answer(app);
            hide_wrongAnswer(app);
            detect_InvalidAnswer(app);
            displayScore(app);
            highlightAllAnswers(app);


        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
           % Clears all content in the axes
            cla(app.UIAxes); 
            cla(app.UIAxes_2);
    
            % Reset variables
           % Clear the stored image data
            app.ansImg = []; 
            app.testImg = [];
            app.EditField.Value = ''; 
            app.EditField_2.Value = 0; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Answer Sheet')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Student Sheet')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.EvaluateResultButton = uibutton(app.Panel, 'push');
            app.EvaluateResultButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateResultButtonPushed, true);
            app.EvaluateResultButton.Text = 'Evaluate Result';
            app.ResetAllButton = uibutton(app.Panel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.LoadCorrectAnswerSheetButton = uibutton(app.Panel, 'push');
            app.LoadCorrectAnswerSheetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCorrectAnswerSheetButtonPushed, true);
            app.LoadCorrectAnswerSheetButton.Text = 'Load Correct Answer Sheet';
            app.LoadStudentAnswerSheetButton = uibutton(app.Panel, 'push');
            app.LoadStudentAnswerSheetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStudentAnswerSheetButtonPushed, true);
            app.LoadStudentAnswerSheetButton.Text = 'Load Student Answer Sheet';
            app.PassingMarksLabel = uilabel(app.Panel);
            app.PassingMarksLabel.Text = 'Passing Marks :';
            app.PassingMarksEditField = uieditfield(app.Panel, 'numeric');
            app.ResultLabel = uilabel(app.Panel);
            app.ResultLabel.Text = 'Result :';
            app.EditField = uieditfield(app.Panel, 'text');
            app.ScoreLabel = uilabel(app.Panel);
            app.ScoreLabel.Text = 'Score :';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.InvalidcountLabel = uilabel(app.Panel);
            app.InvalidcountLabel.Text = 'Invalid count :';
            app.InvalidcountEditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end